classdef equation_finder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClassesLabel                    matlab.ui.control.Label
        optimizeriterationsEditField    matlab.ui.control.NumericEditField
        optimizeriterationsEditFieldLabel  matlab.ui.control.Label
        ExecutiontimeLabel              matlab.ui.control.Label
        weightListBox                   matlab.ui.control.ListBox
        weightListBoxLabel              matlab.ui.control.Label
        parametersListBox               matlab.ui.control.ListBox
        parametersListBoxLabel          matlab.ui.control.Label
        functionEditField               matlab.ui.control.EditField
        weightEditField                 matlab.ui.control.NumericEditField
        parametersEditField             matlab.ui.control.NumericEditField
        addButton                       matlab.ui.control.Button
        removeButton                    matlab.ui.control.Button
        operatorsListBox                matlab.ui.control.ListBox
        operatorsListBoxLabel           matlab.ui.control.Label
        constantoptimizerDropDown       matlab.ui.control.DropDown
        constantoptimizerDropDownLabel  matlab.ui.control.Label
        treeconstantrateEditField       matlab.ui.control.NumericEditField
        treeconstantrateEditFieldLabel  matlab.ui.control.Label
        treeterminaterateEditField      matlab.ui.control.NumericEditField
        treeterminaterateEditFieldLabel  matlab.ui.control.Label
        mutationrateEditField           matlab.ui.control.NumericEditField
        mutationrateEditFieldLabel      matlab.ui.control.Label
        selectionrateEditField          matlab.ui.control.NumericEditField
        selectionrateEditFieldLabel     matlab.ui.control.Label
        savehistoryCheckBox             matlab.ui.control.CheckBox
        importmodelButton               matlab.ui.control.Button
        exportmodelButton               matlab.ui.control.Button
        resetButton                     matlab.ui.control.Button
        stateLabel                      matlab.ui.control.Label
        lossEditField                   matlab.ui.control.EditField
        lossEditFieldLabel              matlab.ui.control.Label
        maxdepthSpinner                 matlab.ui.control.Spinner
        maxdepthSpinnerLabel            matlab.ui.control.Label
        populationsEditField            matlab.ui.control.NumericEditField
        populationsEditFieldLabel       matlab.ui.control.Label
        generationsEditField            matlab.ui.control.NumericEditField
        generationsEditFieldLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        equationEditField               matlab.ui.control.EditField
        equationEditFieldLabel          matlab.ui.control.Label
        solveButton                     matlab.ui.control.Button
        readvariablesButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        data % Description
        GP % Description
        is_solved % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format long;
            addpath("./utils/optimizers");
            addpath("./expression-tree");
%             app.Label.Interpreter = 'latex';
            app.is_solved = false;
            app.stateLabel.Text = "0";
        end

        % Button pushed function: readvariablesButton
        function readvariablesButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", 'Select a File');
            if isequal(file, 0)
                waitfor(msgbox('Data select cancel.'));
            else
                app.data = load(fullfile(path, file));
                app.is_solved = false;
                app.ClassesLabel.Text = sprintf("Classes: %i", size(app.data.x, 2));
                waitfor(msgbox('Data load success.'));
            end
            drawnow; figure(app.UIFigure);
            
        end

        % Button pushed function: solveButton
        function solveButtonPushed(app, event)

            num_classes = size(app.data.x, 2);
            max_iter = app.generationsEditField.Value;
            populations = app.populationsEditField.Value;
            max_depth = app.maxdepthSpinner.Value;
            selection_rate = app.selectionrateEditField.Value;
            mutation_rate = app.mutationrateEditField.Value;
            tree_terminate_rate = app.treeterminaterateEditField.Value;
            tree_operand_rate = 1 - app.treeconstantrateEditField.Value;
            constant_optimizer = app.constantoptimizerDropDown.Value;
            operator_names = cellfun(@(s) convertCharsToStrings(s), app.operatorsListBox.Items);
            operator_argc = cellfun(@(s) str2double(s), app.parametersListBox.Items);
            operator_weights = cellfun(@(s) str2double(s), app.weightListBox.Items);
            const_optimiz_iter = app.optimizeriterationsEditField.Value;
            
            if ~app.is_solved
                app.GP = GeneticProgramming('Classes', num_classes, 'PopulationSize', populations, "TreeMaxDepth", max_depth, ...
                    'SelectionRate', selection_rate, 'MutationRate', mutation_rate, 'TreeTerminateRate', tree_terminate_rate, ...
                    'TreeOperandRate', tree_operand_rate, 'ConstantOptimizer', constant_optimizer, 'Operators', operator_names, ...
                    'OperatorParameters', operator_argc, 'OperatorWeights', operator_weights, 'ConstantOptimizeIterations', const_optimiz_iter);
                app.GP = app.GP.initialize();
                app.is_solved = true;
            end
            app.GP = app.GP.fit(app.data.x, app.data.y, max_iter);

            tree1 = app.GP.optimized_individual_pool{1};
            app.equationEditField.Value = tree1.toInfix();
            app.Label.Text = "$$" + tree1.toLatex(num_classes) + "$$";
            func = tree1.toFunc();
            fitness_val = app.GP.fitness_func(func(app.data.x), app.data.y);
            app.lossEditField.Value = string(fitness_val);
            app.ExecutiontimeLabel.Text = sprintf("Execution time: %.3fs", app.GP.last_execution_time);

            app.stateLabel.Text = string(mod(str2num(app.stateLabel.Text)+1, 10));
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.is_solved = false;
            app.equationEditField.Value = "";
            app.lossEditField.Value = "";
            app.Label.Text = "";
            app.ExecutiontimeLabel.Text = "Execution time:";
        end

        % Button pushed function: exportmodelButton
        function exportmodelButtonPushed(app, event)
            if numel(app.GP) == 0
                return
            end
            [file, path] = uiputfile("*.mat", 'Select a File');
            if isequal(file, 0)
                
            else
                GP = app.GP;
                if app.savehistoryCheckBox.Value
                    GP.history = GeneticProgramming().history;
                end
                save(fullfile(path, file), "GP");
                waitfor(msgbox('Model save success.'));
            end
            drawnow; figure(app.UIFigure);
            
        end

        % Button pushed function: importmodelButton
        function importmodelButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", 'Select a File');
            if isequal(file, 0)
                
            else
                var_obj = load(fullfile(path, file));
                app.GP = var_obj.GP;
                waitfor(msgbox('Modwl load success.'));
                app.lossEditField.Value = "";
                
                % set info of model
                num_classes = app.GP.num_classes;
                tree1 = app.GP.optimized_individual_pool{1};
                app.equationEditField.Value = tree1.toInfix();
                app.Label.Text = "$$" + tree1.toLatex(num_classes) + "$$";
                func = tree1.toFunc();
                if numel(app.data)~=0
                    fitness_val = app.GP.fitness_func(func(app.data.x), app.data.y);
                    app.lossEditField.Value = string(fitness_val);
                end
            end
            drawnow; figure(app.UIFigure);

        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            fun_name = app.functionEditField.Value;
            
            fun_list = app.operatorsListBox.Items;
            idx = find(cellfun(@(s) strcmp(s, fun_name), fun_list), 1);
            
            if numel(idx) == 0
                app.operatorsListBox.Items{end+1} = fun_name;  
                app.parametersListBox.Items{end+1} = num2str(app.parametersEditField.Value);
                app.weightListBox.Items{end+1} = num2str(app.weightEditField.Value);
            else
                app.operatorsListBox.Items{idx} = fun_name;  
                app.parametersListBox.Items{idx} = num2str(app.parametersEditField.Value);
                app.weightListBox.Items{idx} = num2str(app.weightEditField.Value);
            end

        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            fun_name = app.operatorsListBox.Value;
            
            fun_list = app.operatorsListBox.Items;
            idx = find(cellfun(@(s) strcmp(s, fun_name), fun_list), 1);
            
            if numel(idx) ~= 0
                fun_list(idx) = [];
                app.operatorsListBox.Items(idx) = [];  
                app.parametersListBox.Items(idx) = [];
                app.weightListBox.Items(idx) = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.readvariablesButton = uibutton(app.UIFigure, 'push');
            app.readvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @readvariablesButtonPushed, true);
            app.readvariablesButton.Text = 'read variables';
            app.solveButton = uibutton(app.UIFigure, 'push');
            app.solveButton.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solveButton.Text = 'solve';
            app.equationEditFieldLabel = uilabel(app.UIFigure);
            app.equationEditFieldLabel.Text = 'equation';
            app.equationEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '';
            app.generationsEditFieldLabel = uilabel(app.UIFigure);
            app.generationsEditFieldLabel.Text = 'generations';
            app.generationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.generationsEditField.Limits = [1 Inf];
            app.generationsEditField.RoundFractionalValues = 'on';
            app.generationsEditField.Value = 20;
            app.populationsEditFieldLabel = uilabel(app.UIFigure);
            app.populationsEditFieldLabel.Text = 'populations';
            app.populationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.populationsEditField.Limits = [2 Inf];
            app.populationsEditField.RoundFractionalValues = 'on';
            app.populationsEditField.Value = 50;
            app.maxdepthSpinnerLabel = uilabel(app.UIFigure);
            app.maxdepthSpinnerLabel.Text = 'max depth';
            app.maxdepthSpinner = uispinner(app.UIFigure);
            app.maxdepthSpinner.Limits = [1 50];
            app.maxdepthSpinner.Value = 6;
            app.lossEditFieldLabel = uilabel(app.UIFigure);
            app.lossEditFieldLabel.Text = 'loss';
            app.lossEditField = uieditfield(app.UIFigure, 'text');
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'state';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.exportmodelButton = uibutton(app.UIFigure, 'push');
            app.exportmodelButton.ButtonPushedFcn = createCallbackFcn(app, @exportmodelButtonPushed, true);
            app.exportmodelButton.Text = 'export model';
            app.importmodelButton = uibutton(app.UIFigure, 'push');
            app.importmodelButton.ButtonPushedFcn = createCallbackFcn(app, @importmodelButtonPushed, true);
            app.importmodelButton.Text = 'import model';
            app.savehistoryCheckBox = uicheckbox(app.UIFigure);
            app.savehistoryCheckBox.Text = 'save history';
            app.selectionrateEditFieldLabel = uilabel(app.UIFigure);
            app.selectionrateEditFieldLabel.Text = 'selection rate';
            app.selectionrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.selectionrateEditField.Limits = [0 1];
            app.selectionrateEditField.Value = 0.5;
            app.mutationrateEditFieldLabel = uilabel(app.UIFigure);
            app.mutationrateEditFieldLabel.Text = 'mutation  rate';
            app.mutationrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.mutationrateEditField.Limits = [0 1];
            app.mutationrateEditField.Value = 0.05;
            app.treeterminaterateEditFieldLabel = uilabel(app.UIFigure);
            app.treeterminaterateEditFieldLabel.Text = 'tree terminate rate';
            app.treeterminaterateEditField = uieditfield(app.UIFigure, 'numeric');
            app.treeterminaterateEditField.Limits = [0 1];
            app.treeterminaterateEditField.Value = 0.3;
            app.treeconstantrateEditFieldLabel = uilabel(app.UIFigure);
            app.treeconstantrateEditFieldLabel.Text = 'tree constant rate';
            app.treeconstantrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.treeconstantrateEditField.Limits = [0 1];
            app.treeconstantrateEditField.Value = 0.5;
            app.constantoptimizerDropDownLabel = uilabel(app.UIFigure);
            app.constantoptimizerDropDownLabel.Text = 'constant optimizer';
            app.constantoptimizerDropDown = uidropdown(app.UIFigure);
            app.constantoptimizerDropDown.Items = {'levenberg_marquadt', 'particleswarm', 'none'};
            app.constantoptimizerDropDown.Value = 'levenberg_marquadt';
            app.operatorsListBoxLabel = uilabel(app.UIFigure);
            app.operatorsListBoxLabel.Text = 'operators';
            app.operatorsListBox = uilistbox(app.UIFigure);
            app.operatorsListBox.Items = {'+', '-', '.*', './', '.^', 'sin', 'cos'};
            app.operatorsListBox.Value = 'sin';
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.parametersEditField = uieditfield(app.UIFigure, 'numeric');
            app.parametersEditField.Limits = [0 Inf];
            app.parametersEditField.RoundFractionalValues = 'on';
            app.parametersEditField.Value = 1;
            app.weightEditField = uieditfield(app.UIFigure, 'numeric');
            app.weightEditField.Limits = [0 Inf];
            app.weightEditField.Value = 1;
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.functionEditField.Value = 'tan';
            app.parametersListBoxLabel = uilabel(app.UIFigure);
            app.parametersListBoxLabel.Enable = 'off';
            app.parametersListBoxLabel.Text = 'parameters';
            app.parametersListBox = uilistbox(app.UIFigure);
            app.parametersListBox.Items = {'2', '2', '2', '2', '2', '1', '1'};
            app.parametersListBox.Enable = 'off';
            app.parametersListBox.Value = '1';
            app.weightListBoxLabel = uilabel(app.UIFigure);
            app.weightListBoxLabel.Enable = 'off';
            app.weightListBoxLabel.Text = 'weight';
            app.weightListBox = uilistbox(app.UIFigure);
            app.weightListBox.Items = {'5', '5', '4', '4', '2', '1', '1'};
            app.weightListBox.Enable = 'off';
            app.weightListBox.Value = '1';
            app.ExecutiontimeLabel = uilabel(app.UIFigure);
            app.ExecutiontimeLabel.Text = 'Execution time: ';
            app.optimizeriterationsEditFieldLabel = uilabel(app.UIFigure);
            app.optimizeriterationsEditFieldLabel.Text = 'optimizer iterations';
            app.optimizeriterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.optimizeriterationsEditField.Limits = [1 Inf];
            app.optimizeriterationsEditField.RoundFractionalValues = 'on';
            app.optimizeriterationsEditField.Value = 5;
            app.ClassesLabel = uilabel(app.UIFigure);
            app.ClassesLabel.Text = 'Classes:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equation_finder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end